classdef finalwork < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        Panel               matlab.ui.container.Panel
        GridLayout5         matlab.ui.container.GridLayout
        GridLayout6         matlab.ui.container.GridLayout
        Label_25            matlab.ui.control.Label
        Gain_2              matlab.ui.control.Knob
        Button_3            matlab.ui.control.Button
        spec_sweep          matlab.ui.control.StateButton
        Label_13            matlab.ui.control.Label
        EditField_5         matlab.ui.control.NumericEditField
        Label_12            matlab.ui.control.Label
        Gauge               matlab.ui.control.LinearGauge
        Label_11            matlab.ui.control.Label
        MhzLabel_5          matlab.ui.control.Label
        EditField_4         matlab.ui.control.NumericEditField
        Label_9             matlab.ui.control.Label
        MhzLabel_4          matlab.ui.control.Label
        EditField_3         matlab.ui.control.NumericEditField
        EditField_3Label    matlab.ui.control.Label
        MhzLabel_3          matlab.ui.control.Label
        EditField_2         matlab.ui.control.NumericEditField
        Label_8             matlab.ui.control.Label
        Label_21            matlab.ui.control.Label
        Lamp_2              matlab.ui.control.Lamp
        UIAxes              matlab.ui.control.UIAxes
        GridLayout2         matlab.ui.container.GridLayout
        Panel_3             matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        Label_6             matlab.ui.control.Label
        Gain                matlab.ui.control.Knob
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
        DropDown_2          matlab.ui.control.DropDown
        RBWDropDownLabel_2  matlab.ui.control.Label
        RBWDropDown         matlab.ui.control.DropDown
        RBWDropDownLabel    matlab.ui.control.Label
        MHzLabel            matlab.ui.control.Label
        Input_1             matlab.ui.control.NumericEditField
        Label_3             matlab.ui.control.Label
        Lamp_3              matlab.ui.control.Lamp
        Label_7             matlab.ui.control.Label
        Panel_2             matlab.ui.container.Panel
        GridLayout4         matlab.ui.container.GridLayout
        IMDEditField        matlab.ui.control.EditField
        IMDEditFieldLabel   matlab.ui.control.Label
        mEditField          matlab.ui.control.EditField
        mEditFieldLabel     matlab.ui.control.Label
        mEditField_2        matlab.ui.control.EditField
        mLabel              matlab.ui.control.Label
        spec_sweep_2        matlab.ui.control.StateButton
        CognBelief          matlab.ui.control.NumericEditField
        Label_22            matlab.ui.control.Label
        CognMod             matlab.ui.control.EditField
        Label_19            matlab.ui.control.Label
        Switch              matlab.ui.control.ToggleSwitch
        Label_15            matlab.ui.control.Label
        Switch_2            matlab.ui.control.ToggleSwitch
        Label_24            matlab.ui.control.Label
        State               matlab.ui.control.TextArea
        Label_26            matlab.ui.control.Label
        ListBox             matlab.ui.control.ListBox
        Label_14            matlab.ui.control.Label
    end

    
    properties (Access = private)
        finish = 0; % Description
        transmit_pluto=0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function Button_Start(app, event)
%% 频谱分析仪
            %% 设置参数输入
            %RBW
            select_RBW = app.RBWDropDown.Value;
            switch select_RBW
                case '300KHz'
                    RBW = 300e3;
                case '100KHz'
                    RBW = 100e3;
                case '30KHz'
                    RBW=30e3;
                case '10KHz'
                    RBW=10e3;
                case '3KHz'
                    RBW=3e3;
                case '1KHz'
                    RBW=1e3; 
                case '300Hz'
                    RBW=300;
                case '100Hz'
                    RBW=100;
                case '1MHz'
                    RBW=1e6;
                case '3MHz'
                    RBW=3e6;
                case '10MHz'
                    RBW=1e7;
            end

            %分析带宽
            select_bth = app.DropDown_2.Value;
            switch select_bth
                case '50MHz'
                    input_span = 50e6;
                case '20MHz'
                    input_span = 20e6;
                case '10MHz'
                    input_span = 10e6;
                case '5MHz'
                    input_span = 5e6;
                case '1MHz'
                    input_span = 1e6;
            end

            % 校验
            if input_span / RBW < 2
                if RBW >= 1e6
                    r_str = sprintf('%.1fMHz', RBW/1e6);
                elseif RBW >= 1e3
                    r_str = sprintf('%.1fKHz', RBW/1e3);
                else
                    r_str = sprintf('%.0fHz', RBW);
                end
                uialert(app.UIFigure, ...
                    sprintf('Span(%.1fMHz)必须至少是RBW(%s)的2倍!\n请增大分析带宽或减小RBW。', ...
                    input_span/1e6, r_str), ...
                    '参数错误', 'Icon', 'error');
                return;
            end
            master_rate=50e6;

            %% 创建频谱分析仪
            scope = spectrumAnalyzer;
            %scope.AveragingMethod = 'VBW';
            %scope.VBWSource = 'Property';
            %scope.VBW = 10;
            scope.AveragingMethod = 'Exponential';
            scope.ForgettingFactor = 0.6;

            scope.FrequencyResolutionMethod = 'RBW';
            scope.Method = 'Welch';
            scope.Name = '频谱分析仪';
            scope.Window = 'Hann';
            scope.FrequencySpan = 'Span and center frequency';
            
            % 将频谱分析仪的采样率设置为与RWB匹配
            scope.SampleRate = master_rate;
            scope.RBWSource='Property';
            scope.RBW = RBW;
            scope.Span=input_span;

            % 设置接收机
            center_freq = app.Input_1.Value * 1e6; 
            input_gain = app.Gain.Value;
            calFreqHz = [70e6 500e6 1e9 2e9 2.5e9 2.7e9 2.8e9 2.9e9 3e9 4e9 5e9];
            calOffsetDB = [-25 -25 -26 -24 -22.5 -20 -16 -18 -15 -25 -21];
            ampOffsetDB = interp1(calFreqHz, calOffsetDB, center_freq, 'linear', 'extrap');

            rxRadio = sdrrx(...
              'Pluto', ...
              'RadioID', char(resolvePlutoRadioID()), ...
              'CenterFrequency',center_freq, ...
              'BasebandSampleRate',master_rate / 1, ...
              'GainSource', 'Manual', ...
              'Gain',round(input_gain),...
              'OutputDataType', 'single',...
              'SamplesPerFrame',32768);

            while (app.finish==0)
                app.Lamp_3.Color = 'g';
                x = rxRadio();%接受数据帧
                x = x * 10^(ampOffsetDB/20);
                scope(x);
                pause(0.01);
            end
            
            %% 释放频谱分析仪、接收机
            release(scope);
            release(rxRadio);

            %重置finish为0，表示此时可以点击开始
            app.finish = 0;
            %关闭频谱分析仪，指示灯调整为红色
            app.Lamp_3.Color = 'r';
            
        end

        % Button pushed function: Button_2, Button_3
        function Button_Stop(app, event)
%% 设置结束按钮
            app.finish = 1;%按下结束按钮后，将app.finish置为1
        end

        % Value changed function: spec_sweep
        function Spec_sweep(app, event)
%% 频谱扫描仪器
            %% 参数设置：
            start_freq = app.EditField_2.Value*1e6;%起始频率
            stop_freq = app.EditField_3.Value*1e6;%截止频率
            usrp_fs = app.EditField_4.Value*1e6;%采样率
            usrp_gain = app.Gain_2.Value;%增益，从面板旋钮读取
            usrp_dec=1;%抽取因子
            usrp_masterclockrate=usrp_dec*usrp_fs;%AD时钟频率

            %将数据清零
            app.Gauge.Value = 0;
            app.EditField_5.Value = 0;

            % 参数
            nfrmhold            = 20;           % 设置每次重新调整USRP时要接受的帧数
            nfft                = 4096;  % FFT点数自适应：最小4096，RBW≈100Hz级别
            dec_factor          = 16;           % 控制在绘制完整扫描结果之前执行的抽取级别
            overlap             = 0.5;          % 重叠百分比，0.5意味着步进采样率一半
            nfrmdump            = 100;          % 重新调整后要转储的帧数
            % 窗函数（Hann窗，和面板1一致，减频谱泄漏）
            win_vec             = hann(4096, 'periodic');

            % 根据输入的信息计算所需要的步进次数
            usrp_tunerfreq  = start_freq:usrp_fs*overlap:stop_freq;     %调谐器频率范围，单位HZ
            if( max(usrp_tunerfreq) < stop_freq )                         % 检查整个频率范围是否被覆盖，否则添加一个额外的调谐器频率
                usrp_tunerfreq(length(usrp_tunerfreq)+1) = max(usrp_tunerfreq)+usrp_fs*overlap;
            end
            nretunes = length(usrp_tunerfreq);                            %计算所需要的重调次数
           
            %% 配置接收机
            rxRadio = sdrrx(...
              'Pluto', ...
              'RadioID', char(resolvePlutoRadioID()), ...
              'CenterFrequency',usrp_tunerfreq(1), ...
              'BasebandSampleRate',usrp_masterclockrate / usrp_dec, ...
              'GainSource','Manual', ...
              'Gain',round(usrp_gain),...
              'OutputDataType', 'single',...
              'SamplesPerFrame',4096);

             % FIR 抽取器
             obj_decmtr = dsp.FIRDecimator(...
                 'DecimationFactor',     dec_factor,...
                 'Numerator',            fir1(300,1/dec_factor));

             %% 计算
             %usrpsdr_data_fft维度为【1*4096】
             %fft_reorder维度为【20*2048】
             %fft_dec维度为【步进次数*128】
             usrpsdr_data_fft = zeros(1,nfft);                     % 一帧数据4096，保存得到的fft结果
             %数组维度为nfrmhold，即每次调整中心频率后要接受的帧数，长度为重叠长度
             fft_reorder = zeros(nfrmhold,nfft*overlap);  % 具有重叠补偿的矩阵以保持重新排序的ffts [navg x nfft*overlap]
             fft_dec = zeros(nretunes,nfft*overlap/dec_factor);   % 具有重叠补偿的矩阵以保存所有的fftsffts  [ntune x nfft*overlap/data_decimate]
             
             % 创建进度变量
             tune_progress = 0;
             
             % 开始计时
             tic;
             %提示灯显示为绿色
             app.Lamp_2.Color = 'g';
             
             %% 扫描
             for ntune = 1:1:nretunes
                 % 将USRP调整到新的中心频率
                 rxRadio.CenterFrequency = usrp_tunerfreq(ntune);
                 pause(0.01);

                 % 支持中断
                 if app.finish
                     app.Lamp_2.Color = 'r';
                     app.Gauge.Value = 0;              % 进度归零
                     app.EditField_5.Value = 0;        % 计时归零
                     app.finish = 0;                   % 清除标志，允许下次扫描
                     app.spec_sweep.Value = false;     % 按钮弹起
                     release(rxRadio);
                     return;
                 end

                 % 转储帧以清除软件缓冲区，避免数据影响平均值
                 for frm = 1:1:nfrmdump
                     % 从usrp获取一帧，这里的数据是舍弃的
                     usrpsdr_data = step(rxRadio);
                 end

                 % 每一个中心频率处循环接受nfrmhold帧，计算平均
                 for frm = 1:1:nfrmhold

                     % 从USRP上获取一帧
                     usrpsdr_data = step(rxRadio);

                     % 去除直流分量
                     usrpsdr_data = usrpsdr_data - mean(usrpsdr_data);
                     %加Hann窗后FFT
                     usrpsdr_data_fft = abs(fft(usrpsdr_data .* win_vec, nfft))';

                     % 重新排列 fft
                     fft_reorder(frm,( 1 : (overlap*nfft/2) ))      = usrpsdr_data_fft( (overlap*nfft/2)+(nfft/2)+1 : end );   % -ve
                     fft_reorder(frm,( (overlap*nfft/2)+1 : end ))  = usrpsdr_data_fft( 1 : (overlap*nfft/2) );                % +ve

                 end
                 

                 % 求功率平均值
                 fft_reorder_proc = mean(fft_reorder);

                 % 抽取数据以平滑并存储在频谱矩阵中
                 fft_dec(ntune,:) = step(obj_decmtr,fft_reorder_proc')';
                 %扫描进度
                 tune_progress = floor(ntune*100/nretunes);
                 app.Gauge.Value = tune_progress;
             end
             
             %提示灯显示为红色
             app.Lamp_2.Color = 'r';
             fft_masterreshape = reshape(fft_dec',1,ntune*nfft*overlap/dec_factor);
             %计算功率谱
             y_data_dbm = 10*log10(fft_masterreshape.^2);
             %创建X轴
             freq_bin_width = (usrp_fs/nfft);       
             freq_axis = (usrp_tunerfreq(1)-usrp_fs/2*overlap  :  freq_bin_width*dec_factor  :  (usrp_tunerfreq(end)+usrp_fs/2*overlap)-freq_bin_width)/1e6;
                
             calFreqHz = [70e6 500e6 1e9 2e9 2.5e9 2.7e9 2.8e9 2.9e9 3e9 4e9 5e9];
             calOffsetDB = [-25 -25 -26 -24 -22.5 -20 -16 -18 -15 -25 -21];
             y_data_dbm = y_data_dbm + interp1(calFreqHz, calOffsetDB, freq_axis*1e6, 'linear', 'extrap');

             %扫频结果
             plot(app.UIAxes,freq_axis,y_data_dbm,'linewidth',1.25);
             %扫频结果
             plot(app.UIAxes,freq_axis,y_data_dbm,'linewidth',1.25);
             %扫描时间
             app.EditField_5.Value = toc;
             release(rxRadio);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
%% 模式识别
            event = app.Switch.Value;
            start_on = "On";
            if(strcmp(event,start_on))
                %加载神经网络
                appDir = fileparts(mfilename('fullpath'));
                modelFile = fullfile(appDir,'trainedModulationClassificationNetwork.mat');
                if ~isfile(modelFile)
                    error('未找到模型文件: %s', modelFile);
                end
                load(modelFile, 'trainedNet_mine')

                mode_type = app.ListBox.Value;
                sps = 8;
                spf = 1024;
                fs = 200e3;

                %根据调制模式得到数据源
                dataSrc = ModClassGetSource(mode_type, sps, 2*spf, fs);
                %据类型返回调制器函数句柄MOD
                modulator = ModClassGetModulator(mode_type, sps, fs);
                x = dataSrc();
                y = modulator(x);%y为根据所选择的调制模式调制后得到的数据
                center_freq_pluto = 433e6;

                %% 发射机设置
                txRadio = sdrtx('Pluto');
                txRadio.RadioID = char(resolvePlutoRadioID());
                txRadio.CenterFrequency = center_freq_pluto;%中心频率
                txRadio.BasebandSampleRate = fs;%采样速率
                txRadio.Gain = -5;
                %% 接收机设置
               rxRadio = sdrrx(...
                    'Pluto', ...
                    'RadioID', char(resolvePlutoRadioID()), ...
                    'CenterFrequency',center_freq_pluto, ...
                    'BasebandSampleRate',5e6 / 25, ...
                    'GainSource','Manual', ...
                    'Gain',40,...
                    'OutputDataType', 'single',...
                    'SamplesPerFrame', 1024);
                % 发射数据
                %移除滤波器瞬变
                app.State.Value = '发射机开始发送数据';
                y = y(4*sps+1:end,1);
                maxVal = max(max(abs(real(y))), max(abs(imag(y))));
                y = y *0.8/maxVal;
                %将波形信号下载到收音机，并通过无线反复传输
                if(app.transmit_pluto)
                    % 数字中频上变频（与训练时一致：20kHz IF 避让 AC 耦合）
                    t_if = (0:length(y)-1)' / fs;
                    y_if = y .* exp(1j * 2 * pi * 20e3 * t_if);
                    % 截断至整数个 IF 周期，消除循环发送时的相位跳变
                    ifPeriod = round(fs / 20e3);
                    validLen = ifPeriod * floor(length(y_if) / ifPeriod);
                    y_if = y_if(1:validLen);
                    transmitRepeat(txRadio, complex(y_if));
                    %app.mEditField.Value=1;
                else
                    %app.mEditField.Value=0;
                end

                pause(0.2);  % 等待发射机和接收机稳定

                %% 接收数据（多帧提高鲁棒性）
                app.State.Value = '正在识别接收信号';
                % 清除缓存
                for frm = 1:1:40
                    rxRadio();
                end

                % 采集多帧，投票决定
                nFrames = 11;     % 采集帧数（奇数，避免平票）
                cnnVotes   = strings(1, nFrames);
                featVotes  = strings(1, nFrames);
                cnnConfs   = zeros(1, nFrames);
                featConfs  = zeros(1, nFrames);
                cnnAllScores = cell(1, nFrames);

                % 预取网络参数
                inputSize   = trainedNet_mine.Layers(1).InputSize;
                expectedLen = inputSize(2);
                classNames  = categories(trainedNet_mine.Layers(end).Classes);

                for fi = 1:nFrames
                    rx = rxRadio();
                    if fi == 1, rxRawForPlot = rx; end  % 保存第一帧原始数据用于绘图
                    rx = rx - mean(rx);           % 去直流，与训练 pipeline 一致
                    framePower = mean(abs(rx).^2);
                    if framePower > 0
                        rx = rx / sqrt(framePower);
                    end

                    % CNN 推理
                    if length(rx) < expectedLen
                        rx_padded = [rx; zeros(expectedLen - length(rx), 1)];
                    else
                        rx_padded = rx(1:expectedLen);
                    end
                    netInput = zeros(1, expectedLen, 2, 1, 'single');
                    netInput(1,:,1,1) = single(real(rx_padded));
                    netInput(1,:,2,1) = single(imag(rx_padded));
                    scores = double(reshape(predict(trainedNet_mine, netInput), 1, []));
                    [cnnConfs(fi), idx] = max(scores);
                    cnnVotes(fi) = classNames{idx};
                    cnnAllScores{fi} = scores;

                    % 特征分类器推理
                    [fLab, fConf] = classifyFiveClassByFeatures(rx, fs);
                    featVotes(fi) = string(fLab);
                    featConfs(fi) = double(fConf);
                end

                % 多帧 CNN 概率取平均
                cnnScores = mean(cat(1, cnnAllScores{:}), 1);  % 1×5 平均 softmax
                [cnnConf, cnnIdx] = max(cnnScores);
                cnnLabel = classNames{cnnIdx};

                % 多帧特征：取票数最多的结果
                [featLabel, ~] = mode(categorical(featVotes, classNames'));
                featConf = mean(featConfs(featVotes == string(featLabel)));  % 该类平均信心

                % --- 特征结果 → 1×5 伪概率向量 ---
                featScores = (1 - featConf) / (numel(classNames) - 1) * ones(1, numel(classNames));
                featScores(classNames == string(featLabel)) = featConf;

                % --- 加权融合 ---
                featScores = (1 - featConf) / (numel(classNames) - 1) * ones(1, numel(classNames));
                featScores(classNames == string(featLabel)) = featConf;
                alpha = 0.9;
                fusedScores = alpha * cnnScores + (1 - alpha) * featScores;
                [finalConf, finalIdx] = max(fusedScores);
                predic_mod = classNames{finalIdx};

                app.CognMod.Value = char(predic_mod);
                app.CognBelief.Value = double(round(finalConf * 100));
                app.State.Value = sprintf('CNN:%s(%.0f%%) + Feat:%s(%.0f%%) -> %s(%.0f%%)', ...
                    cnnLabel, cnnConf*100, ...
                    string(featLabel), featConf*100, ...
                    predic_mod, finalConf*100);

                %% 绘制接收 IQ 波形和频谱
                try
                    N_plot = numel(rxRawForPlot);
                    t_plot = (0:N_plot-1) / fs * 1e3;
                    Nfft = min(1024, N_plot);
                    [psd_rx, f_psd] = pwelch(rxRawForPlot, hanning(Nfft), Nfft/2, Nfft, fs);

                    figRx = figure('Name', sprintf('接收信号 — %s', predic_mod), ...
                        'NumberTitle', 'off', 'Position', [80 150 900 400]);

                    subplot(1,2,1);
                    plot(t_plot, real(rxRawForPlot), 'b', 'LineWidth', 0.8); hold on;
                    plot(t_plot, imag(rxRawForPlot), 'r', 'LineWidth', 0.8);
                    xlabel('时间 (ms)'); ylabel('幅度');
                    title(sprintf('IQ 时域波形 — %s (%.0f%%)', predic_mod, finalConf*100));
                    legend('I', 'Q', 'Location', 'best'); grid on;

                    subplot(1,2,2);
                    plot(f_psd/1e3, 10*log10(psd_rx + eps), 'k', 'LineWidth', 1.2);
                    xlabel('频率 (kHz)'); ylabel('功率谱密度 (dB/Hz)');
                    title('接收信号功率谱'); grid on;
                    drawnow;
                catch plotME
                    fprintf('绘图失败: %s\n', plotME.message);
                end

                release(rxRadio);
                release(txRadio);

            else
                app.State.Value = '请选择调制模式后按下开关';
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if(strcmp(value,'On'))
                app.transmit_pluto = 1;
                app.State.Value = '发射机已开启，等待模式识别开关';
            else
                app.transmit_pluto = 0;
                app.State.Value = '发射机已关闭';
            end

        end

        % Value changed function: spec_sweep_2
        function AM_modualte(app, event)
%% AM modulation index and IMD live measurement
            app.State.Value = '正在测量AM调制度和交调失真...';
            try
                app.mEditField.InputType = 'text';
                app.IMDEditField.InputType = 'text';
                app.mEditField.Value = '测量中';
                app.IMDEditField.Value = '测量中';
            catch
            end
            try
                targetM = str2double(app.mEditField_2.Value);
                if isnan(targetM) || targetM <= 0 || targetM >= 1
                    targetM = 0.2;  % 无效输入时使用默认值
                end
                
                [mMeasured, imdDbc] = measureAMModulationPluto( ...
                    'CarrierFrequency', 433e6, ...
                    'ModulationFrequency', 7e3, ...
                    'TargetModulationIndex', targetM ,...
                    'PlotDiagnostics', true);

                mText = sprintf('%.3f', mMeasured);
                if isfinite(imdDbc)
                    imdText = sprintf('%.1f dBc', imdDbc);
                else
                    imdText = '< -80 dBc';
                end
                app.State.Value = sprintf('AM测量完成: m=%s, IMD=%s', mText, imdText);
                try
                    app.mEditField.InputType = 'text';
                    app.IMDEditField.InputType = 'text';
                    app.mEditField.Value = mText;
                    app.IMDEditField.Value = imdText;
                catch
                end
            catch ME
                app.State.Value = ['AM测量失败: ' ME.message];
            end
        end

        % Value changed function: Gain_2
        function Gain_2ValueChanged(app, event)
            value = app.Gain_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Panel_2 = uipanel(app.GridLayout2);
            app.Panel_2.Title = '模式识别';
            app.GridLayout4 = uigridlayout(app.Panel_2);
            app.Label_14 = uilabel(app.GridLayout4);
            app.Label_14.Text = '发射机调制模式';
            app.ListBox = uilistbox(app.GridLayout4);
            app.ListBox.Items = {'AM', 'FM', 'ASK', 'FSK', 'BPSK'};
            app.ListBox.Value = 'AM';
            app.Label_26 = uilabel(app.GridLayout4);
            app.Label_26.Text = '状态提示';
            app.State = uitextarea(app.GridLayout4);
            app.Label_24 = uilabel(app.GridLayout4);
            app.Label_24.Text = '发射机发射调制信号';
            app.Switch_2 = uiswitch(app.GridLayout4, 'toggle');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Label_15 = uilabel(app.GridLayout4);
            app.Label_15.Text = '接收机进行模式识别';
            app.Switch = uiswitch(app.GridLayout4, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Label_19 = uilabel(app.GridLayout4);
            app.Label_19.Text = '识别的调制模式';
            app.CognMod = uieditfield(app.GridLayout4, 'text');
            app.Label_22 = uilabel(app.GridLayout4);
            app.Label_22.Text = '识别匹配度';
            app.CognBelief = uieditfield(app.GridLayout4, 'numeric');
            app.spec_sweep_2 = uibutton(app.GridLayout4, 'state');
            app.spec_sweep_2.ValueChangedFcn = createCallbackFcn(app, @AM_modualte, true);
            app.spec_sweep_2.Text = 'AM调制度&交调测量';
            app.mLabel = uilabel(app.GridLayout4);
            app.mLabel.Text = '理论调制度 m';
            app.mEditField_2 = uieditfield(app.GridLayout4, 'text');
            app.mEditFieldLabel = uilabel(app.GridLayout4);
            app.mEditFieldLabel.Text = '调制度 m';
            app.mEditField = uieditfield(app.GridLayout4, 'text');
            app.mEditField.InputType = 'digits';
            app.IMDEditFieldLabel = uilabel(app.GridLayout4);
            app.IMDEditFieldLabel.Text = '交调失真 IMD';
            app.IMDEditField = uieditfield(app.GridLayout4, 'text');
            app.IMDEditField.InputType = 'digits';
            app.Panel_3 = uipanel(app.GridLayout2);
            app.Panel_3.Title = '频谱分析';
            app.GridLayout3 = uigridlayout(app.Panel_3);
            app.Label_7 = uilabel(app.GridLayout3);
            app.Label_7.Text = {'绿灯：运行中；'; '红灯：结束/等待运行'};
            app.Lamp_3 = uilamp(app.GridLayout3);
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = '输入中心频率';
            app.Input_1 = uieditfield(app.GridLayout3, 'numeric');
            app.Input_1.Value = 98.6;
            app.MHzLabel = uilabel(app.GridLayout3);
            app.MHzLabel.Text = 'MHz';
            app.RBWDropDownLabel = uilabel(app.GridLayout3);
            app.RBWDropDownLabel.Text = '选择分辨率RBW';
            app.RBWDropDown = uidropdown(app.GridLayout3);
            app.RBWDropDown.Items = {'300KHz', '100Hz', '1MHz', '3MHz', '10MHz', '100KHz', '30KHz', '10KHz', '3KHz', '1KHz', '300Hz'};
            app.RBWDropDown.Value = '300KHz';
            app.RBWDropDownLabel_2 = uilabel(app.GridLayout3);
            app.RBWDropDownLabel_2.Text = '选择分析带宽';
            app.DropDown_2 = uidropdown(app.GridLayout3);
            app.DropDown_2.Items = {'50MHz', '20MHz', '10MHz', '5MHz', '1MHz'};
            app.DropDown_2.Value = '50MHz';
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button_Start, true);
            app.Button.Text = '开始频谱分析';
            app.Button_2 = uibutton(app.GridLayout3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_Stop, true);
            app.Button_2.Text = '结束';
            app.Gain = uiknob(app.GridLayout3, 'continuous');
            app.Gain.Limits = [0 70];
            app.Gain.Value = 20;
            app.Label_6 = uilabel(app.GridLayout3);
            app.Label_6.Text = '调节增益';
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = '频谱扫描';
            app.GridLayout5 = uigridlayout(app.Panel);
            app.UIAxes = uiaxes(app.GridLayout5);
            title(app.UIAxes, '频谱扫描结果')
            xlabel(app.UIAxes, '频率(MHz)')
            ylabel(app.UIAxes, '相对功率(dB)')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Lamp_2 = uilamp(app.GridLayout6);
            app.Label_21 = uilabel(app.GridLayout6);
            app.Label_21.Text = '绿灯：正在扫描；红灯：扫描结束';
            app.Label_8 = uilabel(app.GridLayout6);
            app.Label_8.Text = '起始频率';
            app.EditField_2 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_2.Limits = [70 Inf];
            app.EditField_2.Value = 70;
            app.MhzLabel_3 = uilabel(app.GridLayout6);
            app.MhzLabel_3.Text = 'Mhz';
            app.EditField_3Label = uilabel(app.GridLayout6);
            app.EditField_3Label.Text = '截止频率';
            app.EditField_3 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_3.Limits = [200 6000];
            app.EditField_3.Value = 200;
            app.MhzLabel_4 = uilabel(app.GridLayout6);
            app.MhzLabel_4.Text = 'Mhz';
            app.Label_9 = uilabel(app.GridLayout6);
            app.Label_9.Text = '采样率';
            app.EditField_4 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_4.Limits = [5 56];
            app.EditField_4.Value = 5;
            app.MhzLabel_5 = uilabel(app.GridLayout6);
            app.MhzLabel_5.Text = 'Mhz';
            app.Label_11 = uilabel(app.GridLayout6);
            app.Label_11.Text = '扫描进度';
            app.Gauge = uigauge(app.GridLayout6, 'linear');
            app.Label_12 = uilabel(app.GridLayout6);
            app.Label_12.Text = '扫描耗时';
            app.EditField_5 = uieditfield(app.GridLayout6, 'numeric');
            app.Label_13 = uilabel(app.GridLayout6);
            app.Label_13.Text = '秒';
            app.spec_sweep = uibutton(app.GridLayout6, 'state');
            app.spec_sweep.ValueChangedFcn = createCallbackFcn(app, @Spec_sweep, true);
            app.spec_sweep.Text = '开始频谱扫描';
            app.Button_3 = uibutton(app.GridLayout6, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_Stop, true);
            app.Button_3.Text = '结束';
            app.Gain_2 = uiknob(app.GridLayout6, 'continuous');
            app.Gain_2.Limits = [0 70];
            app.Gain_2.ValueChangedFcn = createCallbackFcn(app, @Gain_2ValueChanged, true);
            app.Gain_2.Value = 20;
            app.Label_25 = uilabel(app.GridLayout6);
            app.Label_25.Text = '调节增益';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalwork

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end